classdef TaskAppDemo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        ModelFilePath  matlab.ui.control.TextArea
        OpenButton     matlab.ui.control.Button
        InfoButton     matlab.ui.control.Button
    end

    
    properties (Access = public)
        TaskContext % Task Context
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % Find the TaskContext object in varargin
            taskContextIndex = find(cellfun(@(x) isa(x, 'padv.TaskContext'), varargin), 1);

            if ~isempty(taskContextIndex)
                app.TaskContext = varargin{taskContextIndex};

                % Get the IterationArtifact
                iterationArtifact = app.TaskContext.getIterationArtifact();

                % Get the file path from the name field
                filePath = iterationArtifact.name;
                app.ModelFilePath.Value = filePath;
            end
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            % Get the relative path from the name field
            relativePath = app.ModelFilePath.Value;
            % Convert forward slashes to the system-specific separator
            relativePath = strrep(relativePath, '/', filesep);

            % Convert relative path to absolute path
            % We'll use the current working directory as the base
            cp = padv.util.getCurrentProject;
            absolutePath = fullfile(cp.RootFolder, relativePath);
            [~, name, ext] = fileparts(absolutePath);
            if strcmp(ext, '.slx')
                open_system(name);
            else
                open(absolutePath);
            end
        end

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)
            helpURL = "https://www.mathworks.com/help/slcheck/padv/ref/padv.tasktool.html";
            web(helpURL, '-browser');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InfoButton = uibutton(app.UIFigure, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Icon = 'question';
            app.InfoButton.Text = '';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.ModelFilePath = uitextarea(app.UIFigure);
            app.ModelFilePath.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TaskAppDemo(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end